classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure            % UI Figure
        Panel      matlab.ui.container.Panel   % OptimEYES
        Panel2     matlab.ui.container.Panel  
        EditField  matlab.ui.control.EditField % URL
        Button     matlab.ui.control.Button    % Original Webpage
        Button5    matlab.ui.control.Button    % RUN
        EditField3 matlab.ui.control.EditField % Specified Path
        Panel3     matlab.ui.container.Panel  
        Button6    matlab.ui.control.Button    % Optimized Webpage
        Panel4     matlab.ui.container.Panel  
    end


    methods (Access = private)
        
%         function [S] = get_S(app)
%             S = app.EditField2.Value;
%         end

%         function num = get_page(app)
%              if(select_page1)
%               num = 1;
%              elseif(select_page2)
%                  num = 2;
%              elseif(select_page3)
%                  num = 3;
%              else(select_page4)
%                  num = 4;
%              end
%         end
% 
%      end
% 
%     methods (Access = public)
%         
%         function num = get_page(app)
%             if(select_page1)
%               num = 1;
%              elseif(select_page2)
%                  num = 2;
%              elseif(select_page3)
%                  num = 3;
%              else(select_page4)
%                  num = 4;
%              end
%         end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button button pushed function
        function select_page1(app)
  
            close all;% 关闭所有绘图窗口
            warning off;
            page = importdata('page6.mat'); % load a test webpage from our dataset
            % show the webpage with labeled page components
            figure;
            imshow(page.img);
            hold on;
            plot_components(page.components);
            hold on;
            plot_component_text(page.components);

        end

        % Button6 button pushed function
        function run_main(app)
            %             position = app.Panel.Position;
%             clc
%             clear
%             close all
            %%
%             num = get_page;
%             switch num
%                 case 1
%                     page0 = importdata('page1.mat')
%                 case 2
%                     page0 = importdata('page2.mat')
%                 case 3
%                     page0 = importdata('page4.mat')
%                 case 4
%                     page0 = importdata('page6.mat')
            page0 = importdata('page6.mat'); % load a test webpage from our dataset
            page=page0;
            page_input=page0;
            
%             figure;
%             imshow(page.img);
%             hold on;
%             plot_components(page.components);
%             hold on;
%             plot_component_text(page.components);
%             打印出原网页看看
%             figure;
%             imshow(page0.img);
%             hold on;
%             plot_components(page0.components);
%             hold on;
%             plot_component_text(page0.components);
%             
            transition_model = importdata('transition_model.mat'); % load attention transition model
            order_model = importdata('order_model.mat'); % load attention order model
%    end
            %%
            % predict attention transition between every pair of page components and
            % output an attention transition matrix
            TM = predict_mat(page.components, page.img, transition_model);
            % predict attention order between every pair of page components and output
            % an attention order matrix
            OM = predict_mat(page.components, page.img, order_model);
%    end
            %% 期望的组件浏览顺序
            S=[5,6,10,4];
            % S = app.EditField2.Value;
%    end
            %% 计算每个组件的邻居
            alpha=0.4;
            n=length(S);% 输入组件数量 | S |
            for i = 1:(n-1)
                order = S(i);% 当前组件
                nei = [];% 空数组放邻居
                for j = 1:(n-1)
                    hd = compute_distance(page.components{order}, page.components{S(j)}, page.img);
                    if(hd < alpha&&j ~= i+1&&j ~= i)
                        nei = [nei,S(j)];
                    end
                end
                neighbor{i,1} = nei;
            end
            
            E_previous = cal_E(page,page_input,TM,OM,S,neighbor);% 邻居计算要放在外面，然后调用目标函数的end
            %%
            for i=1:10
                disp(i)
                while(1)% 如果成立的话，就循环
                    page=generate_new_page(page0);% 先生成一个新配置的网页
                    TM = predict_mat(page.components, page.img, transition_model);% 新网页的TM和OM矩阵
                    OM = predict_mat(page.components, page.img, order_model);
                    E_new = cal_E(page,page_input,TM,OM,S,neighbor)% 新网页的E
                     if(E_new<E_previous)% 如果变小了，我们要找小
                        disp(E_new)% 打印输出看看跑到哪了
                        page0=page;% 就把当前网页赋给原网页，作为下一次迭代的新网页
                        E_previous=E_new;% 更新E的值
                        break% 不小的话就什么也不做
                    end
                end
            end
            % 打印出新网页看看
            figure;
            imshow(page.img);
            hold on;
            plot_components(page.components);
            hold on;
            plot_component_text(page.components);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'OptimEYES';
            app.Panel.Units = 'pixels';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Units = 'pixels';
            app.Button = uibutton(app.Panel2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @select_page1);
            app.Button.Text = 'Original Webpage';
            app.Button5 = uibutton(app.Panel, 'push');
            app.Button5.Text = 'RUN';
            app.Panel3 = uipanel(app.Panel);
            app.Panel3.Units = 'pixels';
            app.Button6 = uibutton(app.Panel3, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @run_main);
            app.Button6.Text = 'Optimized Webpage';
            app.Panel4 = uipanel(app.Panel);
            app.Panel4.Units = 'pixels';
            app.EditField = uieditfield(app.Panel4, 'text');
            app.EditField.Value = 'URL';
            app.EditField3 = uieditfield(app.Panel4, 'text');
            app.EditField3.Value = 'Specified Path';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end